classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Lamp                  matlab.ui.control.Lamp
        LampLabel             matlab.ui.control.Label
        RValueDisplay         matlab.ui.control.Label
        IIRRValueSlider       matlab.ui.control.Slider
        IIRRValueSliderLabel  matlab.ui.control.Label
        IIRAUDIOButton        matlab.ui.control.Button
        FIRAUDIOButton        matlab.ui.control.Button
        NOISYAUDIOButton      matlab.ui.control.Button
        ORIGINALAUDIOButton   matlab.ui.control.Button
        CLEARButton           matlab.ui.control.Button
        IMPORTButton          matlab.ui.control.Button
        MagIIR                matlab.ui.control.UIAxes
        TimeDomainIIR         matlab.ui.control.UIAxes
        PhaseIIR              matlab.ui.control.UIAxes
        MagFIR                matlab.ui.control.UIAxes
        MagnitudeNA           matlab.ui.control.UIAxes
        MagnitudeOA           matlab.ui.control.UIAxes
        TimeDomainFIR         matlab.ui.control.UIAxes
        PhaseFIR              matlab.ui.control.UIAxes
        TimeDomainNA          matlab.ui.control.UIAxes
        SpectrogramNA         matlab.ui.control.UIAxes
        TimeDomainOA          matlab.ui.control.UIAxes
        SpectrogramOA         matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
    end
    properties (Access = public)
        fullFileName
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: IMPORTButton, SpectrogramOA
        function IMPORTButtonPushed(app, event)
         % Allow user to select an audio file
        [file, path] = uigetfile({'*.wav'; '*.mp3'}, 'Select an Audio File');
        
        % If the user cancels file selection, exit the function
        if isequal(file, 0)
            return;
        end
        
        % Full path of the selected file
        app.fullFileName = fullfile(path, file);
        
        % Load the audio file
        [original_audio, sample_rate] = audioread(app.fullFileName);
        
        % Plot the audio spectra
        plotAudioSpectra(app.SpectrogramOA, original_audio, sample_rate, "Original Audio");
        plotTimeDomain(app.TimeDomainOA, original_audio, sample_rate, "Original Audio");
        plotMagnitudeSpectrum(app.MagnitudeOA, original_audio, sample_rate, "Original Audio");
        

        %% Add Noise to Original File

        % Define noise frequencies in Hz and amplitudes
        noise_freqs = [1500, 3060, 3510, 3569.9, 3920, 5180, 5679.9, 6040, 6129.9, 6369.9];
        noise_amps = 0.1 * ones(size(noise_freqs));  % Set amplitude for each noise component
        
        % Generate noise signal based on specified frequencies and amplitudes
        time_vector = (0:length(original_audio)-1) / sample_rate; % Time vector for signal
        noise_signal = zeros(size(original_audio));  % Initialize noise signal
        for i = 1:length(noise_freqs)
            noise_signal = noise_signal + noise_amps(i) * sin(2 * pi * noise_freqs(i) * time_vector');
        end
        
        % Combine noise with the original audio signal
        noisy_audio = original_audio + noise_signal;

        % Save the noisy audio to a file
        audiowrite('NoisyAudio.wav', noisy_audio, sample_rate);
        
        % Calculate the Fourier Transform to identify spectral locations of noise
        N = length(noisy_audio);
        noisy_audio_fft = fft(noisy_audio);
        frequency_vector = (0:N-1) * (sample_rate / N); % Frequency vector for FFT
        plotMagnitudeSpectrum(app.MagnitudeNA,noisy_audio,sample_rate, "Noisy Audio");
        plotAudioSpectra(app.SpectrogramNA, noisy_audio, sample_rate, "Noisy Audio");
        plotTimeDomain(app.TimeDomainNA, noisy_audio, sample_rate, "Noisy Audio");
        % Find noise peaks in the positive half of the spectrum
        % [~, noise_peaks] = findpeaks(abs(noisy_audio_fft((N/2+1):end)), frequency_vector((N/2+1):end), 'MinPeakHeight', 0.01);
        % Display identified noise frequencies
        % disp("Frequencies of Noise (Hz):")
        % disp(noise_peaks);
        % 
        % disp("Discrete Normalized Frequencies of Noise:")
        % disp(noise_peaks / sample_rate);
        %% START Filtering NoisyAudio
        % Reload the noisy audio file for filtering steps
        % Define radii for IIR filters
        [noisy_audio, sample_rate] = audioread('NoisyAudio.wav');
        
        radius_2 = app.IIRRValueSlider.Value;
        L = length(noisy_audio);
        noisy_audio_fft = fftshift(fft(noisy_audio)) / L;
        frequency_vector = -sample_rate/2:sample_rate/L:sample_rate/2 - sample_rate/L;
        
        [notused, noise_peaks] = findpeaks(abs(noisy_audio_fft(( ...
            length(noisy_audio_fft)+1)/2:end)), ...
            frequency_vector((length(noisy_audio_fft)+1)/2:end), ...
            'MinPeakHeight', 0.01);
        fir_coefficients = 1;  % Start with 1 as the initial coefficient

        for i = 1:length(noise_peaks)
            % Calculate the cosine term for the current noise frequency
            cosine_term = 2 * cos(2 * pi * noise_peaks(i) / sample_rate);

            % Update FIR coefficients by convolving with the current term (z^2 - 2*cos(w)*z + 1)
            fir_coefficients = conv(fir_coefficients, [1, -cosine_term, 1]);
        end
        plotphase(app.PhaseFIR,fir_coefficients,1,"FIR Filter")
        plotMag(app.MagFIR,fir_coefficients,1, "FIR FIlter")
        filtered_fir_audio = filter(fir_coefficients, 1, noisy_audio);
        audiowrite("FIRaudio_filtered.wav", filtered_fir_audio / ...
            max(abs(filtered_fir_audio(:))) * 0.5, sample_rate)
        plotTimeDomain(app.TimeDomainFIR, noisy_audio, sample_rate, "FIR FIlter");
        %% IIR FILTER
        % iir_denom_2 = 1;
        % for i = 1:length(noise_peaks)
        %    iir_denom_2 = iir_denom_2 * (z^2 - 2 * radius_2 * cos(2 * pi * noise_peaks(i) / sample_rate) * z + radius_2^2);
        % end
        % iir_coefficients_2 = sym2poly(iir_denom_2);

        iir_denom_2 = 1;  % Start with the constant polynomial 1
        for i = 1:length(noise_peaks)
            % Define the current term as a numeric polynomial [1, -2 * r * cos(theta), r^2]
            theta = 2 * pi * noise_peaks(i) / sample_rate;
            current_term = [1, -2 * radius_2 * cos(theta), radius_2^2];
            
            % Multiply current term with the existing polynomial
            iir_denom_2 = conv(iir_denom_2, current_term);
        end
        % iir_denom_2 is now the numeric polynomial coefficients for the IIR filter
        iir_coefficients_2 = iir_denom_2;
        filtered_iir_audio_2 = filter(fir_coefficients, iir_coefficients_2, noisy_audio);
        audiowrite("IIR2audio_filtered.wav", filtered_iir_audio_2 / ...
            max(abs(filtered_iir_audio_2(:))) * 0.5, sample_rate)
        plotphase(app.PhaseIIR,fir_coefficients, iir_coefficients_2, "IIR Filter")
        plotTimeDomain(app.TimeDomainIIR,  filtered_iir_audio_2 / ...
            max(abs(filtered_iir_audio_2(:))) * 0.5, sample_rate, "IIR Filter")
        plotMag(app.MagIIR,fir_coefficients, iir_coefficients_2, "IIR Filter")
        %% TODO TRY TO MAKE THE syms z WORK
        % syms z
        % fir_numerator = 1;  % FIR filter numerator (product of terms for each noise frequency)
        % for i = 1:length(noise_peaks)
        %    fir_numerator = fir_numerator * (z^2 - 2 * cos(2 * pi * noise_peaks(i) / sample_rate) * z + 1);
        % end
        % fir_coefficients = sym2poly(fir_numerator);
        function plotMag(app, fir_coefficients,num, str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            mag_response = abs(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, mag_response);
            title(app, 'Magnitude Response of '+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
        end
        function plotphase(app, fir_coefficients,num,str)
            % Define frequency range for the phase plot
            w = 0:0.001:2*pi;
        
            % Compute the frequency response of the FIR filter
            H = freqz(fir_coefficients, num, w);
        
            % Plot phase response
            phase_response = angle(H);  % Get phase angle of the frequency response
        
            % Plot on the designated UIAxes in the app
            plot(app, w/pi, phase_response);
            title(app, 'Phase Response of '+str);
            xlabel(app, 'Frequency \times\pi ');
            ylabel(app, 'Phase');
            grid(app, 'on');
        end
        % Function to plot spectrogram, time-domain, and magnitude spectra
        function plotAudioSpectra(app, audio, sample_rate, str)
            % Plot the Spectrogram in SpectrogramOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            set(app,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            title(app, 'Spectrogram of '+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Frequency ');
            ylim(app, [0 inf]);
            xlim(app, [0 T(end)])
            grid(app, 'on');
        end
        function plotTimeDomain(app, audio, sample_rate, str)
            % Plot the Time Domain in TimeDomainOA
            [S,F,T] = spectrogram(audio, 256, 200, 256, sample_rate, 'yaxis');
            imagesc(app, T, F, log(1+abs(S)) ); %plot the log spectrum
            timeVector = (0:length(audio) - 1) / sample_rate;
            plot(app, timeVector, audio);
            title(app, 'Time Domain of '+str);
            xlabel(app, 'Time ');
            ylabel(app, 'Amplitude');
            xlim(app, [0 T(end)])
            grid(app, 'on');
        end
        function plotMagnitudeSpectrum(app, audio, sample_rate, str)
            % Plot the Magnitude Spectrum in MagnitudeOA
            audio_fft = fft(audio);
            n = length(audio);
            f = (0:n/2-1) * (sample_rate / n);  % Frequency vector up to Nyquist frequency
            magnitude = abs(audio_fft(1:n/2));  % Magnitude of the FFT
            plot(app, f, magnitude);
            title(app, 'Magnitude Spectrum of '+str);
            xlabel(app, 'Frequency');
            ylabel(app, 'Magnitude');
            grid(app, 'on');
            xlim(app, [0 f(end)])
        end

        end

        % Button pushed function: ORIGINALAUDIOButton
        function ORIGINALAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread(app.fullFileName);
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Play the audio

            playblocking(player);
        end

        % Button pushed function: NOISYAUDIOButton
        function NOISYAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread('NoisyAudio.wav');
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Play the audio

            playblocking(player);
        end

        % Button pushed function: FIRAUDIOButton
        function FIRAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread('FIRaudio_filtered.wav');
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Play the audio

            playblocking(player); 
        end

        % Button pushed function: IIRAUDIOButton
        function IIRAUDIOButtonPushed(app, event)
            if isempty(app.fullFileName)
                uialert(app.UIFigure, 'No audio file selected.', 'Error');
                return;
            end
            
            % Load the audio data and sample rate
            [original_audio, sample_rate] = audioread('IIR2audio_filtered.wav');
            
            % Create an audioplayer object
            player = audioplayer(original_audio, sample_rate);
            
            % Play the audio

            playblocking(player);     
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.fullFileName = [];
            cla(app.MagIIR);
            cla(app.MagFIR);
            cla(app.MagnitudeNA);
            cla(app.MagnitudeOA);
            cla(app.PhaseFIR);
            cla(app.PhaseIIR);
            cla(app.SpectrogramNA);
            cla(app.SpectrogramOA);
            cla(app.TimeDomainNA);
            cla(app.TimeDomainOA);
            cla(app.TimeDomainFIR);
            cla(app.TimeDomainIIR);
        end

        % Value changed function: IIRRValueSlider
        function IIRRValueSliderValueChanged(app, event)
            value = app.IIRRValueSlider.Value;
            % Display the value in RValueDisplay with two decimal places
            app.RValueDisplay.Text = sprintf('%.2f', value);
        end

        % Value changing function: IIRRValueSlider
        function IIRRValueSliderValueChanging(app, event)
            value = app.IIRRValueSlider.Value;
            % Display the value in RValueDisplay with two decimal places
            app.RValueDisplay.Text = sprintf('%.2f', value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrogramOA = uiaxes(app.UIFigure);
            title(app.SpectrogramOA, 'Spectrogram of Original Audio')
            xlabel(app.SpectrogramOA, 'Time')
            ylabel(app.SpectrogramOA, 'Frequency')
            zlabel(app.SpectrogramOA, 'Z')
            app.SpectrogramOA.ButtonDownFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.TimeDomainOA = uiaxes(app.UIFigure);
            title(app.TimeDomainOA, 'Time Domain of Original Audio')
            xlabel(app.TimeDomainOA, 'Time')
            ylabel(app.TimeDomainOA, 'Amplitude')
            zlabel(app.TimeDomainOA, 'Z')
            app.SpectrogramNA = uiaxes(app.UIFigure);
            title(app.SpectrogramNA, 'Spectrogram of Noisy Audio')
            xlabel(app.SpectrogramNA, 'Time')
            ylabel(app.SpectrogramNA, 'Frequency')
            zlabel(app.SpectrogramNA, 'Z')
            app.TimeDomainNA = uiaxes(app.UIFigure);
            title(app.TimeDomainNA, 'Time Domain of Noisy Audio')
            xlabel(app.TimeDomainNA, 'Time')
            ylabel(app.TimeDomainNA, 'Amplitude')
            zlabel(app.TimeDomainNA, 'Z')
            app.PhaseFIR = uiaxes(app.UIFigure);
            title(app.PhaseFIR, 'Phase Response of FIR Filter')
            xlabel(app.PhaseFIR, 'Frequency\times\pi ')
            ylabel(app.PhaseFIR, 'Phase')
            zlabel(app.PhaseFIR, 'Z')
            app.TimeDomainFIR = uiaxes(app.UIFigure);
            title(app.TimeDomainFIR, 'Time Domain of FIR Filter')
            xlabel(app.TimeDomainFIR, 'Time')
            ylabel(app.TimeDomainFIR, 'Amplitude')
            zlabel(app.TimeDomainFIR, 'Z')
            app.MagnitudeOA = uiaxes(app.UIFigure);
            title(app.MagnitudeOA, 'Magnitude Spectrum of Original Audio')
            xlabel(app.MagnitudeOA, 'Frequency')
            ylabel(app.MagnitudeOA, 'Magnitude')
            zlabel(app.MagnitudeOA, 'Z')
            app.MagnitudeNA = uiaxes(app.UIFigure);
            title(app.MagnitudeNA, 'Magnitude Spectrum of Noisy Audio')
            xlabel(app.MagnitudeNA, 'Frequency')
            ylabel(app.MagnitudeNA, 'Magnitude')
            zlabel(app.MagnitudeNA, 'Z')
            app.MagFIR = uiaxes(app.UIFigure);
            title(app.MagFIR, 'Magnitude Response of FIR Filter')
            xlabel(app.MagFIR, 'Frequency\times\pi ')
            ylabel(app.MagFIR, 'Magnitude')
            zlabel(app.MagFIR, 'Z')
            app.PhaseIIR = uiaxes(app.UIFigure);
            title(app.PhaseIIR, 'Phase Response of IIR Filter')
            xlabel(app.PhaseIIR, 'Frequency\times\pi ')
            ylabel(app.PhaseIIR, 'Phase')
            zlabel(app.PhaseIIR, 'Z')
            app.TimeDomainIIR = uiaxes(app.UIFigure);
            title(app.TimeDomainIIR, 'Time Domain of IIR Filter')
            xlabel(app.TimeDomainIIR, 'Time')
            ylabel(app.TimeDomainIIR, 'Amplitude')
            zlabel(app.TimeDomainIIR, 'Z')
            app.MagIIR = uiaxes(app.UIFigure);
            title(app.MagIIR, 'Magnitude Response of IIR Filter')
            xlabel(app.MagIIR, 'Frequency\times\pi ')
            ylabel(app.MagIIR, 'Magnitude')
            zlabel(app.MagIIR, 'Z')
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ORIGINALAUDIOButton = uibutton(app.UIFigure, 'push');
            app.ORIGINALAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @ORIGINALAUDIOButtonPushed, true);
            app.ORIGINALAUDIOButton.Text = 'ORIGINAL AUDIO';
            app.NOISYAUDIOButton = uibutton(app.UIFigure, 'push');
            app.NOISYAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @NOISYAUDIOButtonPushed, true);
            app.NOISYAUDIOButton.Text = 'NOISY AUDIO';
            app.FIRAUDIOButton = uibutton(app.UIFigure, 'push');
            app.FIRAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @FIRAUDIOButtonPushed, true);
            app.FIRAUDIOButton.Text = 'FIR AUDIO';
            app.IIRAUDIOButton = uibutton(app.UIFigure, 'push');
            app.IIRAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @IIRAUDIOButtonPushed, true);
            app.IIRAUDIOButton.Text = 'IIR AUDIO';
            app.IIRRValueSliderLabel = uilabel(app.UIFigure);
            app.IIRRValueSliderLabel.Text = 'IIR R Value';
            app.IIRRValueSlider = uislider(app.UIFigure);
            app.IIRRValueSlider.Limits = [0 1];
            app.IIRRValueSlider.ValueChangedFcn = createCallbackFcn(app, @IIRRValueSliderValueChanged, true);
            app.IIRRValueSlider.ValueChangingFcn = createCallbackFcn(app, @IIRRValueSliderValueChanging, true);
            app.IIRRValueSlider.Value = 0.95;
            app.RValueDisplay = uilabel(app.UIFigure);
            app.RValueDisplay.Text = '0.95';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end